classdef VerifyAnnotationsV19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel_3                      matlab.ui.container.Panel
        TimespansEditField           matlab.ui.control.NumericEditField
        TimespansEditFieldLabel      matlab.ui.control.Label
        FFTWindowEditField           matlab.ui.control.NumericEditField
        FFTWindowEditFieldLabel      matlab.ui.control.Label
        MinFrequencyEditField        matlab.ui.control.NumericEditField
        MinFrequencyEditFieldLabel   matlab.ui.control.Label
        MaxFrequencyEditField        matlab.ui.control.NumericEditField
        MaxFrequencyEditFieldLabel   matlab.ui.control.Label
        OverlapEditField             matlab.ui.control.NumericEditField
        OverlapLabel                 matlab.ui.control.Label
        cancelNoise                  matlab.ui.control.CheckBox
        UndodeleteButton             matlab.ui.control.Button
        ShowdeletedButton            matlab.ui.control.Button
        FileEditField                matlab.ui.control.EditField
        FileEditFieldLabel           matlab.ui.control.Label
        CommentEditField             matlab.ui.control.EditField
        CommentEditFieldLabel        matlab.ui.control.Label
        ClassificationDropDown_2     matlab.ui.control.DropDown
        PlaySoundButton              matlab.ui.control.Button
        StoreNew                     matlab.ui.control.Button
        AddAnn                       matlab.ui.control.Button
        DeleteAnnButton              matlab.ui.control.Button
        SavefileButton               matlab.ui.control.Button
        StoreAnnButton               matlab.ui.control.Button
        ClassificationDropDown       matlab.ui.control.DropDown
        ClassificationDropDownLabel  matlab.ui.control.Label
        TotalNEditFieldLabel         matlab.ui.control.Label
        TotalN                       matlab.ui.control.NumericEditField
        ModifyAnnTimesButton         matlab.ui.control.Button
        Knob                         matlab.ui.control.DiscreteKnob
        KnobLabel                    matlab.ui.control.Label
        LoadingLamp                  matlab.ui.control.Lamp
        sthresholdCheckBox           matlab.ui.control.CheckBox
        setThreshold                 matlab.ui.control.NumericEditField
        AnnotationSpinner            matlab.ui.control.Spinner
        AnnotationSpinnerLabel       matlab.ui.control.Label
        DisplayN                     matlab.ui.control.EditField
        AnnotationNLabel             matlab.ui.control.Label
        LoadAnnotationsButton        matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Panel_2                      matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        N % Description
        filenames
        datastart
        dataend
        dur
        idxlist
        filenames2
        datastart2
        dataend2
        dur2
        idxlist2
        n
        fs
        fftwin
        overlap
        oldF
        Stotal
        x
        y
        clipstart
        clas
        claslist
        annTable3
        annTable
        files
        paths
        deletelist
        wavpath
        newAnn
        x3
        S
        comments
        maxf
        minf
        MaxFreq
        MinFreq
        idxAct
        fsAct
        noise
        fsInit
        ts
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadAnnotationsButton
        function LoadAnnotationsButtonPushed(app, event)
            app.fftwin = 4096;
            app.overlap = .7;
            app.MaxFreq = 2048;
            app.MinFreq = 16;
            app.ts=5;


            app.oldF = [];
            app.LoadingLamp.Color = 'red';
            app.LoadingLamp.Enable = 'on';
            app.deletelist = [];
            app.annTable3 = [];
            app.deletelist = [];
            app.newAnn =[];
            app.x = [];


            [csvfile,csvpath]=uigetfile('*xlsx');

            app.wavpath = uigetdir('','Select Directory for wavfiles');

            %% Set up the Import Options and import the data
            opts = spreadsheetImportOptions("NumVariables", 7);
            
            % Specify sheet and range
            opts.DataRange = "A2";
            
            % Specify column names and types
            opts.VariableNames = ["FileNumber", "CallStartTime", "CallEndTime", "VocalType", "Quality", "SavedClipName", "Comments"];
            opts.VariableTypes = ["string", "string", "string", "categorical", "categorical", "string", "categorical"];
            
            % Specify variable properties
            opts = setvaropts(opts, ["FileNumber", "CallStartTime", "CallEndTime", "SavedClipName"], "WhitespaceRule", "preserve");
            opts = setvaropts(opts, ["FileNumber", "CallStartTime", "CallEndTime", "VocalType", "Quality", "SavedClipName", "Comments"], "EmptyFieldRule", "auto");
            
            % Import the data

            % Find format
                [~,~,ext] = fileparts(fullfile(csvpath,csvfile));
                ext = lower(ext);
            switch ext
                case '.csv'
            opts = detectImportOptions(fullfile(csvpath,csvfile), 'NumHeaderLines', 0);
            opts.VariableNames = ["FileNumber", "CallStartTime", "CallEndTime", "VocalType", "Quality", "SavedClipName", "Comments"];
            opts.VariableTypes = ["string", "double", "double", "categorical", "categorical", "string", "categorical"];
            
            % Specify variable properties
            % opts = setvaropts(opts, ["FileNumber", "CallStartTime", "CallEndTime", "SavedClipName"], "WhitespaceRule", "preserve");
            % opts = setvaropts(opts, ["FileNumber", "CallStartTime", "CallEndTime", "VocalType", "Quality", "SavedClipName", "Comments"], "EmptyFieldRule", "auto");
            
            annTable = readtable(fullfile(csvpath,csvfile), opts);

                otherwise
                annTable = readtable(fullfile(csvpath,csvfile), opts, "UseExcel", false);              
            end
            
            % Clear temporary variables
            clear opts
            
            % end

            clear app.newAnn

            app.filenames = string(annTable{:,1});
            for mm=1:length(app.filenames)
                filetype = strfind(app.filenames(mm),'wav');
                if isempty(filetype)==0
                    oldfilename=char(app.filenames(mm));
                    app.filenames(mm)=string(oldfilename(1:end-4));
                end
            end
            % filestarts = cell2mat(convertStringsToChars(app.filenames));
            % filestarts = filestarts(:,11:end);
            % formatIn = 'yymmddHHMMSS';
            % filestartsST = datenum(filestarts,formatIn);

            % Ainfo = audioinfo(fullfile(app.wavpath,sprintf('%s.wav',app.filenames(1))));
            % app.fs = Ainfo.SampleRate;
            filelist=unique(app.filenames);
            app.fs=zeros(length(app.filenames),1);
            for n=1:length(filelist)
                info = audioinfo(fullfile(app.wavpath,sprintf('%s.wav',filelist(n))));
                idx = find(app.filenames==filelist(n));
                app.fs(idx)= info.SampleRate;
            end
            app.fsInit=app.fs;

            callstart = annTable{:,2};
            for m=1:length(callstart)
                colstr=strfind(callstart(m),':');
                if length(colstr)==1
                    callstartT(m) = duration(callstart(m),'InputFormat','mm:ss.SS');
                elseif length(colstr)==0
                    callstartT(m) = seconds(callstart(m));
                else
                    callstartT(m) = duration(callstart(m),'InputFormat','hh:mm:ss.SS');
                end
            end

            % callstart = annTable{:,2};
            % callstartT = duration(callstart,'InputFormat','mm:ss.SS');
            callstartS = seconds(callstartT)';

            callend = annTable{:,3};
            for m=1:length(callend)
                colstr=strfind(callend(m),':');
                if length(colstr)==1
                    callendT(m) = duration(callend(m),'InputFormat','mm:ss.SS');
                elseif length(colstr)==0
                    callendT(m) = seconds(callend(m));
                else
                    callendT(m) = duration(callend(m),'InputFormat','hh:mm:ss.SS');
                end
            end
            % callendT = duration(callend,'InputFormat','mm:ss.SS');
            callendS = seconds(callendT)';
            
            app.datastart = round(callstartS.*app.fs);
            app.dataend = round(callendS.*app.fs);
            
            datadur = app.dataend-app.datastart;
            app.dur = datadur./app.fs;

            app.N = length(app.dur);
            app.idxlist = linspace(1,length(app.dur),length(app.dur));

            app.filenames2 = app.filenames;
            app.datastart2 = app.datastart;
            app.dataend2 = app.dataend;
            app.dur2 = app.dur;
            app.idxlist2 = app.idxlist;
            app.idxAct = app.idxlist;

            
            app.annTable3 = annTable;
            app.clas = annTable{:,4};
            app.claslist = sort(unique(app.clas));
            app.ClassificationDropDown.Items = app.claslist;
            app.ClassificationDropDown_2.Items = app.claslist;
            app.comments =annTable{:,7};

            app.annTable = app.annTable3;

            app.LoadingLamp.Enable = 'off';
            app.n = 1;
            app.AnnotationSpinner.Value = app.n;
            app.AnnotationSpinnerValueChanged


            app.files = csvfile;
            app.paths = csvpath;
            
            Didx = strfind(app.claslist,app.clas(app.n));
            app.ClassificationDropDown.Value = sprintf('%s',app.clas(app.idxlist(app.n)));

            app.deletelist = [];

            
        end

        % Value changed function: AnnotationSpinner
        function AnnotationSpinnerValueChanged(app, event)
            N = app.N;
            n = app.AnnotationSpinner.Value;
            app.DisplayN.Value = sprintf('%d',app.idxlist(n));
            app.CommentEditField.Value = string(app.comments(app.idxlist(n)));
            app.AnnotationSpinner.Limits = [1 N];
            filenames = app.filenames;
            datastart = app.datastart;
            dataend = app.dataend;
            fs = app.fs;
            fftwin = app.fftwin;
            overlap = app.overlap;
            app.LoadingLamp.Enable = 'on';

         
    actF = filenames(n);
    old=app.oldF;
    fileactlist=filenames==actF;
    tf = strcmp(actF,app.oldF);
    app.LoadingLamp.Enable = 'on';

    if tf == 0
        actF;
        [app.Stotal,app.fsAct] = audioread(fullfile(app.wavpath,sprintf('%s.wav',actF)));
        app.FileEditField.Value = actF;
    end


    randX=rand*10;
    % save('test44.mat','datastart')
    app.clipstart = round(datastart(n)-(app.ts*app.fsAct));
    if app.dur(n)>0
        clipend = round(dataend(n)+((app.ts*app.fsAct)));
    else
        clipend = round(datastart(n)+((25*app.fsAct)));
    end
    if clipend>length(app.Stotal)
        S = app.Stotal(end-(30*app.fsAct):end);
    elseif app.clipstart<1
        S = app.Stotal(1:30*app.fsAct);
    else
        S = app.Stotal(app.clipstart:clipend);
    end
    [s, fr, t] = spectrogram(S,fftwin,round(overlap*fftwin),fftwin,app.fsAct,'yaxis');
    % s = melSpectrogram(S,fs, ...
    %                'Window',hann(fftwin,'periodic'), ...
    %                'OverlapLength',round(overlap*fftwin), ...
    %                'FFTLength',fftwin, ...
    %                'NumBands',128, ...
    %                'FrequencyRange',[16,2000]);


    s = abs(s);
    app.maxf = find(fr<app.MaxFreq,1,'last');
    app.minf = find (fr<app.MinFreq,1,'last');
    fr = fr(app.minf:app.maxf);
    s = s(app.minf:app.maxf,:);
    % s = flipud(abs(s));
    if app.noise == 1
        s=s-median(s,2);
    end
  
    %imagesc(t,fr,imadjust(s))
    imagesc(app.UIAxes,t+(app.clipstart/app.fsAct),fr,imadjust(s))
    % xlim([app.clipstart/app.fs clipend/app.fs])
    % xl = xticklabels
    % save('testx.mat','xl')
    % xlnew=cell2mat(xl)+app.clipstart;
    % xticklabels(xlnew)

    app.LoadingLamp.Enable = 'off';
    
    actann = find(datastart >= app.clipstart & dataend < clipend & fileactlist==1);
    
    ylines = [min(fr)*ones(1,length(actann)) ; max(fr)*ones(1,length(actann))];
    ylinesn = [min(fr)*ones(1,length(n)) ; max(fr)*ones(1,length(n))];
    % line(app.UIAxes,[(datastart(actann)'-app.clipstart)/fs ; (datastart(actann)'-app.clipstart)/fs],ylines,'color','g')
    % line(app.UIAxes,[(dataend(actann)'-app.clipstart)/fs ; (dataend(actann)'-app.clipstart)/fs],ylines,'color','g')
    % line(app.UIAxes,[(datastart(n)'-app.clipstart)/fs ; (datastart(n)'-app.clipstart)/fs],ylines,'color','r')
    % line(app.UIAxes,[(dataend(n)'-app.clipstart)/fs ; (dataend(n)'-app.clipstart)/fs],ylines,'color','r')
    line(app.UIAxes,[(datastart(actann)')/app.fsAct ; (datastart(actann)')/app.fsAct],ylines,'color','g')
    line(app.UIAxes,[(dataend(actann)')/app.fsAct ; (dataend(actann)')/app.fsAct],ylines,'color','g')
    line(app.UIAxes,[(datastart(n)')/app.fsAct ; (datastart(n)')/app.fsAct],ylines,'color','r')
    line(app.UIAxes,[(dataend(n)')/app.fsAct ; (dataend(n)')/app.fsAct],ylines,'color','r')


    axis(app.UIAxes,'tight') 
    set(app.UIAxes,'YDir', 'normal');
    % axis tight
    % set(gca,'YDir','normal')
    % 
    % ss=imadjust(flip(s,1));
    app.ClassificationDropDown.BackgroundColor = [1 1 1];
    app.ClassificationDropDown.Value = sprintf('%s',app.clas(app.idxlist(n)));

    % imwrite(ss,'testimage.png');
    row1=[app.annTable.CallStartTime(app.idxlist(n)) app.annTable.CallEndTime(app.idxlist(n))];
    row2=[app.annTable3.CallStartTime(app.idxlist(n)) app.annTable3.CallEndTime(app.idxlist(n))];

    if isequal(row1,row2)==0
        line(app.UIAxes,[(datastart(n)')/app.fsAct ; (datastart(n)')/app.fsAct],ylines,'color','y')
        line(app.UIAxes,[(dataend(n)')/app.fsAct ; (dataend(n)')/app.fsAct],ylines,'color','y')
    end

    if isequal(app.annTable.VocalType(app.idxlist(n)),app.annTable3.VocalType(app.idxlist(n)))  == 0
        app.ClassificationDropDown.Value = sprintf('%s',app.annTable3.VocalType(app.idxlist(n)));
        % tt = app.idxlist(n);
        % save('test2.mat','tt')
        app.ClassificationDropDown.BackgroundColor = "#F77A8F";
    end

    app.oldF = actF;
    app.N = length(app.dur);
    app.TotalN.Value = app.N;
    app.n=n;
    app.S = S;
    AT = app.annTable3;
    DT = app.deletelist;
    save('annBackup.mat','AT','DT')
    % tt=sprintf('%s',app.clas(app.idxlist(app.n)))

    % sprintf('%s',app.clas(app.idxlist(app.n)))


        end

        % Value changed function: DisplayN
        function DisplayNValueChanged(app, event)
            
            app.DisplayN.Value = app.n;
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            key = event.Key;

            switch key
    case 'x'
        app.AnnotationSpinner.Value = app.AnnotationSpinner.Value+1;
        app.DisplayN.Value = sprintf('%d',app.n);
        app.AnnotationSpinnerValueChanged
    case 'z'
        app.AnnotationSpinner.Value = app.AnnotationSpinner.Value-1;
        app.DisplayN.Value = sprintf('%d',app.n);
        app.AnnotationSpinnerValueChanged
    case 's'
        app.PlaySoundButtonPushed
    case 'd'
        clear sound
end
            
        end

        % Value changed function: sthresholdCheckBox
        function sthresholdCheckBoxValueChanged(app, event)
            
            value = app.sthresholdCheckBox.Value;
            threshold = app.setThreshold.Value;
            if value == 1
                idx = find(app.dur2>threshold);
                app.datastart = app.datastart2(idx);
                app.filenames = app.filenames2(idx);
                app.dataend = app.dataend2(idx);
                app.dur = app.dur(idx);
                app.idxlist = app.idxlist2(idx);
            else
                app.datastart = app.datastart2;
                app.filenames = app.filenames2;
                app.dataend = app.dataend2;
                app.dur = app.dur2;
                app.idxlist = app.idxlist2;

            end
            app.N = length(app.dur);

        end

        % Value changed function: setThreshold
        function setThresholdValueChanged(app, event)
            % value = app.setThreshold.Value;
              
            app.KnobValueChanged
            app.AnnotationSpinnerValueChanged

        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            
            value = app.Knob.Value;

            threshold = app.setThreshold.Value;

            if strcmp(value,'Off') 
                
                % app.MinFreq = app.annTable3.MinFreq;
                % app.MaxFreq = app.annTable3.MaxFreq;
                 app.filenames = app.annTable3{:,1};
                 for mm=1:length(app.filenames)
                    filetype = strfind(app.filenames(mm),'wav');
                    if isempty(filetype)==0
                        oldfilename=char(app.filenames(mm));
                        app.filenames(mm)=string(oldfilename(1:end-4));
                   end
                end

                callstart = app.annTable3{:,2};
                for m=1:length(callstart)
                    colstr=strfind(callstart(m),':');
                    if length(colstr)==1
                        callstartT(m) = duration(callstart(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callstartT(m) = seconds(callstart(m));
                    else
                        callstartT(m) = duration(callstart(m),'InputFormat','hh:mm:ss.SS');
                    end
                end        
            callstartS = seconds(callstartT)';
               
                callend = app.annTable3{:,3};
                for m=1:length(callend)
                    colstr=strfind(callend(m),':');
                    if length(colstr)==1
                        callendT(m) = duration(callend(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callendT(m) = seconds(callend(m));
                    else
                        callendT(m) = duration(callend(m),'InputFormat','hh:mm:ss.SS');
                    end
                end
               
                callendS = seconds(callendT)';

                app.fs = app.fsInit;
            
                app.datastart = round(callstartS.*app.fs);
                app.dataend = round(callendS.*app.fs);

                datadur = app.dataend-app.datastart;
                app.dur = datadur./app.fs;


                % app.datastart = app.datastart2;
                % app.filenames = app.filenames2;
                % app.dataend = app.dataend2;
                % adpp.ur = app.dur2;
                % app.clas = app.clas2;
                % app.MinFreq = app.MinFreq2;
                % app.MaxFreq = app.MaxFreq2;
                app.idxAct = app.idxlist2;
                app.idxlist = app.idxlist2;

            elseif strcmp(value,'Neg Dur')
                idx = find(app.dur2<=0)
                if isempty(idx)==1
                    msgbox('No calls meet the conditions set, please use different settings.')
                else
                % app.MinFreq = app.annTable3.MinFreq(idx);
                % app.MaxFreq = app.annTable3.MaxFreq(idx);
                 app.filenames = app.annTable3{idx,1};
                 for mm=1:length(app.filenames)
                    filetype = strfind(app.filenames(mm),'wav');
                    if isempty(filetype)==0
                        oldfilename=char(app.filenames(mm));
                        app.filenames(mm)=string(oldfilename(1:end-4));
                   end
                end

                callstart = app.annTable3{idx,2};
                for m=1:length(callstart)
                    colstr=strfind(callstart(m),':');
                    if length(colstr)==1
                        callstartT(m) = duration(callstart(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callstartT(m) = seconds(callstart(m));
                    else
                        callstartT(m) = duration(callstart(m),'InputFormat','hh:mm:ss.SS');
                    end
                end        
            callstartS = seconds(callstartT)';
               
                callend = app.annTable3{idx,3};
                for m=1:length(callend)
                    colstr=strfind(callend(m),':');
                    if length(colstr)==1
                        callendT(m) = duration(callend(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callendT(m) = seconds(callend(m));
                    else
                        callendT(m) = duration(callend(m),'InputFormat','hh:mm:ss.SS');
                    end
                end
               
                callendS = seconds(callendT)';

                app.fs = app.fsInit(idx);
            
                app.datastart = round(callstartS.*app.fs);
                app.dataend = round(callendS.*app.fs);

                datadur = app.dataend-app.datastart;
                app.dur = datadur./app.fs;

                % app.datastart = app.datastart2(idx);
                % app.filenames = app.filenames2(idx);
                % app.dataend = app.dataend2(idx);
                % app.dur = app.dur2(idx);
                % app.clas = app.clas2(idx);
                % app.MinFreq = app.MinFreq2(idx);
                % app.MaxFreq = app.MaxFreq2(idx);
                app.idxAct = idx;
                app.idxlist = app.idxlist2(idx);
                app.TotalN.Value = length(callendS);
                end

            elseif strcmp(value,'Classification')
                clasvalue = app.ClassificationDropDown_2.Value;
                idx = find(app.clas == clasvalue);
                % app.MinFreq = app.annTable3.MinFreq(idx);
                % app.MaxFreq = app.annTable3.MaxFreq(idx);
                app.filenames = app.annTable3{idx,1};
                for mm=1:length(app.filenames)
                    filetype = strfind(app.filenames(mm),'wav');
                    if isempty(filetype)==0
                        oldfilename=char(app.filenames(mm));
                        app.filenames(mm)=string(oldfilename(1:end-4));
                   end
                end

                callstart = app.annTable3{idx,2};
                for m=1:length(callstart)
                    colstr=strfind(callstart(m),':');
                    if length(colstr)==1
                        callstartT(m) = duration(callstart(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callstartT(m) = seconds(callstart(m));
                    else
                        callstartT(m) = duration(callstart(m),'InputFormat','hh:mm:ss.SS');
                    end
                end        
            callstartS = seconds(callstartT)';
               
                callend = app.annTable3{idx,3};
                for m=1:length(callend)
                    colstr=strfind(callend(m),':');
                    if length(colstr)==1
                        callendT(m) = duration(callend(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callendT(m) = seconds(callend(m));
                    else
                        callendT(m) = duration(callend(m),'InputFormat','hh:mm:ss.SS');
                    end
                end
               
                callendS = seconds(callendT)';

                app.fs = app.fsInit(idx);
            
                app.datastart = round(callstartS.*app.fs);
                app.dataend = round(callendS.*app.fs);

                datadur = app.dataend-app.datastart;
                app.dur = datadur./app.fs;


                % app.datastart = app.datastart2(idx);
                % app.filenames = app.filenames2(idx);
                % app.dataend = app.dataend2(idx);
                % app.dur = app.dur2(idx);
                % app.clas = app.clas2(idx);
                % app.MinFreq = app.MinFreq2(idx);
                % app.MaxFreq = app.MaxFreq2(idx);
                app.idxAct = idx;
                app.idxlist = app.idxlist2(idx);
           

            elseif strcmp(value,'Duration above')
                idx = find(app.dur2>threshold);
                if isempty(idx)==1
                    msgbox('No calls meet the conditions set, please use different settings.')
                else

                % app.MinFreq = app.annTable3.MinFreq(idx);
                % app.MaxFreq = app.annTable3.MaxFreq(idx);
                 app.filenames = app.annTable3{idx,1};
                 for mm=1:length(app.filenames)
                    filetype = strfind(app.filenames(mm),'wav');
                    if isempty(filetype)==0
                        oldfilename=char(app.filenames(mm));
                        app.filenames(mm)=string(oldfilename(1:end-4));
                   end
                end

                callstart = app.annTable3{idx,2};
                for m=1:length(callstart)
                    colstr=strfind(callstart(m),':');
                    if length(colstr)==1
                        callstartT(m) = duration(callstart(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callstartT(m) = seconds(callstart(m));
                    else
                        callstartT(m) = duration(callstart(m),'InputFormat','hh:mm:ss.SS');
                    end
                end        
            callstartS = seconds(callstartT)';
               
                callend = app.annTable3{idx,3};
                for m=1:length(callend)
                    colstr=strfind(callend(m),':');
                    if length(colstr)==1
                        callendT(m) = duration(callend(m),'InputFormat','mm:ss.SS');
                    elseif length(colstr)==0
                        callendT(m) = seconds(callend(m));
                    else
                        callendT(m) = duration(callend(m),'InputFormat','hh:mm:ss.SS');
                    end
                end
               
                callendS = seconds(callendT)';

                app.fs = app.fsInit(idx);
            
                app.datastart = round(callstartS.*app.fs);
                app.dataend = round(callendS.*app.fs);

                datadur = app.dataend-app.datastart;
                app.dur = datadur./app.fs;
                app.TotalN.Value = length(callendS);


                % app.datastart = app.datastart2(idx);
                % app.filenames = app.filenames2(idx);
                % app.dataend = app.dataend2(idx);
                % app.dur = app.dur2(idx);
                % app.clas = app.clas2(idx);
                % app.MinFreq = app.MinFreq2(idx);
                % app.MaxFreq = app.MaxFreq2(idx);
                app.idxlist = app.idxlist2(idx);
                app.idxAct = idx;
                end
            end

            app.N = length(app.dur);
            app.TotalN.Value = app.N;
            app.AnnotationSpinner.Limits = [1 app.N];

        end

        % Button pushed function: ModifyAnnTimesButton
        function ModifyAnnTimesButtonPushed(app, event)
            
            roi = drawline(app.UIAxes);
            % x2 = [app.clipstart+(min(roi.Position(:,1))*app.fs) app.clipstart+(max(roi.Position(:,1))*app.fs)]; 
            x2 = [(min(roi.Position(:,1))*app.fsAct) (max(roi.Position(:,1))*app.fsAct)];
            % save('testann.mat','x2')
            app.x=x2;

        end

        % Value changed function: TotalN
        function TotalNValueChanged(app, event)
            
            value = app.TotalN.Value;


        end

        % Value changed function: ClassificationDropDown
        function ClassificationDropDownValueChanged(app, event)
 
            value = app.ClassificationDropDown.Value;


        end

        % Button pushed function: StoreAnnButton
        function StoreAnnButtonPushed(app, event)

            app.annTable3.VocalType(str2double(app.DisplayN.Value)) = app.ClassificationDropDown.Value;
            if length(app.x)>=2
                t1 = seconds(app.x(1))/app.fsAct;
                t1.Format = 'mm:ss.SS';
                t2 = seconds(app.x(2)/app.fsAct);
                t2.Format = 'mm:ss.SS';
                app.annTable3.CallStartTime(str2double(app.DisplayN.Value)) = t1;
                app.annTable3.CallEndTime(str2double(app.DisplayN.Value)) = t2;

                callstart = app.annTable3.CallStartTime(app.idxAct);
                callstartT = duration(callstart,'InputFormat','mm:ss.SS');
                callstartS = seconds(callstartT);

                callend = app.annTable3.CallEndTime(app.idxAct);
                callendT = duration(callend,'InputFormat','mm:ss.SS');
                callendS = seconds(callendT);
            
                app.datastart = round(callstartS*app.fsAct);
                app.dataend = round(callendS*app.fsAct);

            end

        end

        % Button pushed function: SavefileButton
        function SavefileButtonPushed(app, event)
 
            app.files
            newname = sprintf('%s_mod.xlsx',app.files(1:end-4));
            [newfile,newpath] = uiputfile(fullfile(app.paths,newname));
            tableFinal = app.annTable3;
            tableFinal(app.deletelist,:) = [];
            writetable(tableFinal,fullfile(newpath,newfile))

            newfileM = sprintf('%s.mat',newfile(1:end-4));
            annTable3 = app.annTable3;
            annTable = app.annTable;
            deletelist = app.deletelist;
            save(fullfile(newpath,newfileM),'annTable3','annTable','deletelist')

        end

        % Button pushed function: DeleteAnnButton
        function DeleteAnnButtonPushed(app, event)
            
            app.deletelist = [app.deletelist ; str2double(app.DisplayN.Value)];
            app.deletelist = sort(unique(app.deletelist));
            D=app.deletelist;

        end

        % Button pushed function: AddAnn
        function AddAnnButtonPushed(app, event)
            
           roi = drawline(app.UIAxes);
            app.x3 = [(min(roi.Position(:,1))*app.fsAct) (max(roi.Position(:,1))*app.fsAct)];


        end

        % Button pushed function: StoreNew
        function StoreNewButtonPushed(app, event)
            
             app.newAnn = app.annTable(1,:);
             app.newAnn.FileNumber(1) = app.oldF;
             app.newAnn.VocalType(1) = app.ClassificationDropDown.Value;
             t1 = seconds(app.x3(1))/app.fsAct;
             t1.Format = 'mm:ss.SS';
             t2 = seconds(app.x3(2)/app.fsAct);
             t2.Format = 'mm:ss.SS';
             app.newAnn.CallStartTime(1) = t1;
             app.newAnn.CallEndTime(1) = t2;
             app.newAnn.Comments(1) = 'New Annotation';
             % app.newAnn(1,end) = table();

             app.annTable3 = [app.annTable3 ; app.newAnn]; 

        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            SN = app.S/max(abs(app.S));
            sound(SN,app.fsAct)
        end

        % Value changed function: ClassificationDropDown_2
        function ClassificationDropDown_2ValueChanged(app, event)
            
            value = app.Knob.Value;

            if strcmp(value,'Classification')
                clasvalue = app.ClassificationDropDown_2.Value;
                idx = find(app.clas == clasvalue);
                app.datastart = app.datastart2(idx);
                app.filenames = app.filenames2(idx);
                app.dataend = app.dataend2(idx);
                app.dur = app.dur2(idx);
                app.idxlist = app.idxlist2(idx);

                app.N = length(app.dur);
                app.TotalN.Value = app.N;
                app.AnnotationSpinner.Limits = [1 app.N];
            end
            
        end

        % Value changed function: CommentEditField
        function CommentEditFieldValueChanged(app, event)
            value = app.CommentEditField.Value;
            
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            value = app.FileEditField.Value;
            
        end

        % Button pushed function: ShowdeletedButton
        function ShowdeletedButtonPushed(app, event)
            
            fig = uifigure('Position',[100 100 752 250]);
            uit = uitable('Parent',fig,'Position',[25 50 700 200]);
            id = linspace(1,height(app.annTable3),height(app.annTable3))';
            T4 = [table(id) app.annTable3];
            uit.Data = T4(app.deletelist,:);


        end

        % Button pushed function: UndodeleteButton
        function UndodeleteButtonPushed(app, event)
            
            if length(app.deletelist)>=1
                app.deletelist = app.deletelist(1:end-1);
            else
                msgbox('List of deleted annotatins is zero')
            end

        end

        % Value changed function: cancelNoise
        function cancelNoiseValueChanged(app, event)
            app.noise = app.cancelNoise.Value;
            
        end

        % Value changed function: FFTWindowEditField
        function FFTWindowEditFieldValueChanged(app, event)
            app.fftwin = app.FFTWindowEditField.Value;
            AnnotationSpinnerValueChanged(app, event)
            
        end

        % Value changed function: OverlapEditField
        function OverlapEditFieldValueChanged(app, event)
            app.overlap = app.OverlapEditField.Value/100;
            AnnotationSpinnerValueChanged(app, event)
        end

        % Value changed function: MaxFrequencyEditField
        function MaxFrequencyEditFieldValueChanged(app, event)
            app.MaxFreq = app.MaxFrequencyEditField.Value;
            AnnotationSpinnerValueChanged(app, event)
        end

        % Value changed function: MinFrequencyEditField
        function MinFrequencyEditFieldValueChanged(app, event)
            app.MinFreq = app.MinFrequencyEditField.Value;
            AnnotationSpinnerValueChanged(app, event)
        end

        % Value changed function: TimespansEditField
        function TimespansEditFieldValueChanged(app, event)
            app.ts = app.TimespansEditField.Value/2;
            AnnotationSpinnerValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Frequency (Hz)')
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel = uipanel(app.UIFigure);
            app.LoadAnnotationsButton = uibutton(app.UIFigure, 'push');
            app.LoadAnnotationsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnnotationsButtonPushed, true);
            app.LoadAnnotationsButton.Text = 'Load Annotations';
            app.AnnotationNLabel = uilabel(app.UIFigure);
            app.AnnotationNLabel.Text = 'Annotation N';
            app.DisplayN = uieditfield(app.UIFigure, 'text');
            app.DisplayN.ValueChangedFcn = createCallbackFcn(app, @DisplayNValueChanged, true);
            app.AnnotationSpinnerLabel = uilabel(app.UIFigure);
            app.AnnotationSpinnerLabel.Text = 'Annotation #';
            app.AnnotationSpinner = uispinner(app.UIFigure);
            app.AnnotationSpinner.ValueChangedFcn = createCallbackFcn(app, @AnnotationSpinnerValueChanged, true);
            app.setThreshold = uieditfield(app.UIFigure, 'numeric');
            app.setThreshold.ValueChangedFcn = createCallbackFcn(app, @setThresholdValueChanged, true);
            app.setThreshold.Value = 1;
            app.sthresholdCheckBox = uicheckbox(app.UIFigure);
            app.sthresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @sthresholdCheckBoxValueChanged, true);
            app.sthresholdCheckBox.Enable = 'off';
            app.sthresholdCheckBox.Visible = 'off';
            app.sthresholdCheckBox.Text = 'Set threshold';
            app.LoadingLamp = uilamp(app.UIFigure);
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = '';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'Off', 'Neg Dur', 'Classification', 'Duration above'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.ModifyAnnTimesButton = uibutton(app.UIFigure, 'push');
            app.ModifyAnnTimesButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyAnnTimesButtonPushed, true);
            app.ModifyAnnTimesButton.Text = 'Modify Ann Times';
            app.TotalN = uieditfield(app.UIFigure, 'numeric');
            app.TotalN.ValueChangedFcn = createCallbackFcn(app, @TotalNValueChanged, true);
            app.TotalNEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNEditFieldLabel.Text = 'Total N';
            app.ClassificationDropDownLabel = uilabel(app.UIFigure);
            app.ClassificationDropDownLabel.Text = 'Classification';
            app.ClassificationDropDown = uidropdown(app.UIFigure);
            app.ClassificationDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassificationDropDownValueChanged, true);
            app.StoreAnnButton = uibutton(app.UIFigure, 'push');
            app.StoreAnnButton.ButtonPushedFcn = createCallbackFcn(app, @StoreAnnButtonPushed, true);
            app.StoreAnnButton.Text = 'Store Ann';
            app.SavefileButton = uibutton(app.UIFigure, 'push');
            app.SavefileButton.ButtonPushedFcn = createCallbackFcn(app, @SavefileButtonPushed, true);
            app.SavefileButton.Text = 'Save file';
            app.DeleteAnnButton = uibutton(app.UIFigure, 'push');
            app.DeleteAnnButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAnnButtonPushed, true);
            app.DeleteAnnButton.Text = 'Delete Ann';
            app.AddAnn = uibutton(app.UIFigure, 'push');
            app.AddAnn.ButtonPushedFcn = createCallbackFcn(app, @AddAnnButtonPushed, true);
            app.AddAnn.Text = 'Add Ann';
            app.StoreNew = uibutton(app.UIFigure, 'push');
            app.StoreNew.ButtonPushedFcn = createCallbackFcn(app, @StoreNewButtonPushed, true);
            app.StoreNew.Text = 'Store New';
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.ClassificationDropDown_2 = uidropdown(app.UIFigure);
            app.ClassificationDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ClassificationDropDown_2ValueChanged, true);
            app.CommentEditFieldLabel = uilabel(app.UIFigure);
            app.CommentEditFieldLabel.Text = 'Comment';
            app.CommentEditField = uieditfield(app.UIFigure, 'text');
            app.CommentEditField.ValueChangedFcn = createCallbackFcn(app, @CommentEditFieldValueChanged, true);
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.ShowdeletedButton = uibutton(app.UIFigure, 'push');
            app.ShowdeletedButton.ButtonPushedFcn = createCallbackFcn(app, @ShowdeletedButtonPushed, true);
            app.ShowdeletedButton.Text = 'Show deleted';
            app.UndodeleteButton = uibutton(app.UIFigure, 'push');
            app.UndodeleteButton.ButtonPushedFcn = createCallbackFcn(app, @UndodeleteButtonPushed, true);
            app.UndodeleteButton.Text = 'Undo delete';
            app.cancelNoise = uicheckbox(app.UIFigure);
            app.cancelNoise.ValueChangedFcn = createCallbackFcn(app, @cancelNoiseValueChanged, true);
            app.cancelNoise.Text = 'Cancel Noise';
            app.Panel_3 = uipanel(app.UIFigure);
            app.OverlapLabel = uilabel(app.Panel_3);
            app.OverlapLabel.Text = 'Overlap %';
            app.OverlapEditField = uieditfield(app.Panel_3, 'numeric');
            app.OverlapEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapEditFieldValueChanged, true);
            app.OverlapEditField.Value = 70;
            app.MaxFrequencyEditFieldLabel = uilabel(app.Panel_3);
            app.MaxFrequencyEditFieldLabel.Text = 'Max Frequency';
            app.MaxFrequencyEditField = uieditfield(app.Panel_3, 'numeric');
            app.MaxFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MaxFrequencyEditFieldValueChanged, true);
            app.MaxFrequencyEditField.Value = 2048;
            app.MinFrequencyEditFieldLabel = uilabel(app.Panel_3);
            app.MinFrequencyEditFieldLabel.Text = 'Min Frequency';
            app.MinFrequencyEditField = uieditfield(app.Panel_3, 'numeric');
            app.MinFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @MinFrequencyEditFieldValueChanged, true);
            app.MinFrequencyEditField.Value = 16;
            app.FFTWindowEditFieldLabel = uilabel(app.Panel_3);
            app.FFTWindowEditFieldLabel.Text = 'FFT Window';
            app.FFTWindowEditField = uieditfield(app.Panel_3, 'numeric');
            app.FFTWindowEditField.ValueChangedFcn = createCallbackFcn(app, @FFTWindowEditFieldValueChanged, true);
            app.FFTWindowEditField.Value = 4096;
            app.TimespansEditFieldLabel = uilabel(app.Panel_3);
            app.TimespansEditFieldLabel.Text = 'Time span (s)';
            app.TimespansEditField = uieditfield(app.Panel_3, 'numeric');
            app.TimespansEditField.ValueChangedFcn = createCallbackFcn(app, @TimespansEditFieldValueChanged, true);
            app.TimespansEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VerifyAnnotationsV19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end